classdef MoveGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ConveyorMovePanel       matlab.ui.container.Panel
        LoadingBayButton        matlab.ui.control.Button
        RobotBayButton          matlab.ui.control.Button
        ConnectionLampLabel     matlab.ui.control.Label
        ConnectionLamp          matlab.ui.control.Lamp
        RobotReadyLampLabel     matlab.ui.control.Label
        RobotReadyLamp          matlab.ui.control.Lamp
        ConveyorReadyLampLabel  matlab.ui.control.Label
        ConveyorReadyLamp       matlab.ui.control.Lamp
        SimpleMovePanel         matlab.ui.container.Panel
        DropDown                matlab.ui.control.DropDown
        ResetButton             matlab.ui.control.Button
        MOVEButton              matlab.ui.control.Button
        BP1EditFieldLabel       matlab.ui.control.Label
        BP1EditField            matlab.ui.control.EditField
        BP2EditFieldLabel       matlab.ui.control.Label
        BP2EditField            matlab.ui.control.EditField
        StatusEditField_2Label  matlab.ui.control.Label
        StatusEditField_2       matlab.ui.control.EditField
        DecksPanel              matlab.ui.container.Panel
        DropDown_2              matlab.ui.control.DropDown
        FILLButton              matlab.ui.control.Button
        DISCARDButton           matlab.ui.control.Button
        SORTButton              matlab.ui.control.Button
        CANCELButton            matlab.ui.control.Button
        StatusEditField_3Label  matlab.ui.control.Label
        StatusEditField_3       matlab.ui.control.EditField
        TablePanel              matlab.ui.container.Panel
        CLEARButton             matlab.ui.control.Button
        FILLButton_2            matlab.ui.control.Button
        PathPlanningPanel       matlab.ui.container.Panel
        StatusEditFieldLabel    matlab.ui.control.Label
        StatusEditField         matlab.ui.control.EditField
        NAVIGATEButton          matlab.ui.control.Button
        ResetButton_2           matlab.ui.control.Button
        StartBPEditFieldLabel   matlab.ui.control.Label
        StartBPEditField        matlab.ui.control.EditField
        GoalBPEditFieldLabel    matlab.ui.control.Label
        GoalBPEditField         matlab.ui.control.EditField
        TicTacToePanel          matlab.ui.container.Panel
        PLAYERvPLAYERButton     matlab.ui.control.Button
        AIvPLAYERButton         matlab.ui.control.Button
        StatusEditField_4Label  matlab.ui.control.Label
        StatusEditField_4       matlab.ui.control.EditField
    end


    properties (Access = public)
        LoadingBayButtonPressed = 0;
        RobotBayButtonPressed = 0; 
        MOVEButtonPressed = 0;
        CANCELButtonPressed = 0;
        TESTButtonPressed = 0;
        FILLButtonPressed = 0;
        DISCARDButtonPressed = 0;
        SORTButtonPressed = 0;
        CLEARButtonPressed = 0;
        TableFILLButtonPressed = 0;
        NAVIGATEButtonPressed = 0;
        PvPButtonPressed = 0;
        AIvPButtonPressed = 0;
    end


    methods (Access = private)

        % Button pushed function: LoadingBayButton
        function LoadingBayButtonPushed(app, event)
            app.LoadingBayButtonPressed = 1;
        end

        % Button pushed function: RobotBayButton
        function RobotBayButtonPushed(app, event)
            app.RobotBayButtonPressed = 1;
        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            app.MOVEButtonPressed = 1;
            if(strcmp(app.MOVEButton.Text,"MOVE"))
                app.MOVEButton.Text = "WAIT";
            else
                app.MOVEButton.Text = "MOVE";
            end
        end

        % Button pushed function: CANCELButton
        function CANCELButtonPushed(app, event)
            app.CANCELButtonPressed = 1;
            app.MOVEButton.Text = "MOVE";
        end

        % Callback function
        function TESTButtonPushed(app, event)
            app.TESTButtonPressed = 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.BP1EditField.Value = '';
            app.BP2EditField.Value = '';
            app.DropDown.Value = 'BP to Conveyor';
        end

        % Button pushed function: FILLButton
        function FILLButtonPushed(app, event)
            app.FILLButtonPressed = 1;
        end

        % Button pushed function: DISCARDButton
        function DISCARDButtonPushed(app, event)
            app.DISCARDButtonPressed = 1;
        end

        % Button pushed function: SORTButton
        function SORTButtonPushed(app, event)
            app.SORTButtonPressed = 1;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.CLEARButtonPressed = 1;
        end

        % Button pushed function: FILLButton_2
        function FILLButton_2Pushed(app, event)
            app.TableFILLButtonPressed = 1;
        end

        % Button pushed function: NAVIGATEButton
        function NAVIGATEButtonPushed(app, event)
            app.StatusEditField.Value = "Navigating...";
            app.NAVIGATEButtonPressed = 1;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.StartBPEditField.Value = '';
            app.GoalBPEditField.Value = '';
            app.StatusEditField.Value = "Ready";
        end

        % Button pushed function: PLAYERvPLAYERButton
        function PLAYERvPLAYERButtonPushed(app, event)
            app.PvPButtonPressed = 1;
        end

        % Button pushed function: AIvPLAYERButton
        function AIvPLAYERButtonPushed(app, event)
            app.AIvPButtonPressed = 1;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(strcmp(value,'Rotate BP'))
                app.BP2EditFieldLabel.Text = 'Deg';
            else
                app.BP2EditFieldLabel.Text = 'BP 2';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConveyorMovePanel = uipanel(app.UIFigure);
            app.ConveyorMovePanel.Title = 'Conveyor Move';
            app.LoadingBayButton = uibutton(app.ConveyorMovePanel, 'push');
            app.LoadingBayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingBayButtonPushed, true);
            app.LoadingBayButton.Text = {'Loading'; ' Bay'};
            app.RobotBayButton = uibutton(app.ConveyorMovePanel, 'push');
            app.RobotBayButton.ButtonPushedFcn = createCallbackFcn(app, @RobotBayButtonPushed, true);
            app.RobotBayButton.Text = {'Robot'; 'Bay'};
            app.ConnectionLampLabel = uilabel(app.ConveyorMovePanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.ConveyorMovePanel);
            app.RobotReadyLampLabel = uilabel(app.ConveyorMovePanel);
            app.RobotReadyLampLabel.Text = 'Robot Ready';
            app.RobotReadyLamp = uilamp(app.ConveyorMovePanel);
            app.ConveyorReadyLampLabel = uilabel(app.ConveyorMovePanel);
            app.ConveyorReadyLampLabel.Text = 'Conveyor Ready';
            app.ConveyorReadyLamp = uilamp(app.ConveyorMovePanel);
            app.SimpleMovePanel = uipanel(app.UIFigure);
            app.SimpleMovePanel.Title = 'Simple Move';
            app.DropDown = uidropdown(app.SimpleMovePanel);
            app.DropDown.Items = {'BP to Conveyor', 'Conveyor to BP', 'BP to BP', 'Rotate BP'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'BP to Conveyor';
            app.ResetButton = uibutton(app.SimpleMovePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MOVEButton = uibutton(app.SimpleMovePanel, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Text = 'MOVE';
            app.BP1EditFieldLabel = uilabel(app.SimpleMovePanel);
            app.BP1EditFieldLabel.Text = 'BP 1';
            app.BP1EditField = uieditfield(app.SimpleMovePanel, 'text');
            app.BP2EditFieldLabel = uilabel(app.SimpleMovePanel);
            app.BP2EditFieldLabel.Text = 'BP 2';
            app.BP2EditField = uieditfield(app.SimpleMovePanel, 'text');
            app.StatusEditField_2Label = uilabel(app.SimpleMovePanel);
            app.StatusEditField_2Label.Text = 'Status';
            app.StatusEditField_2 = uieditfield(app.SimpleMovePanel, 'text');
            app.StatusEditField_2.Editable = 'off';
            app.StatusEditField_2.Value = 'Ready';
            app.DecksPanel = uipanel(app.UIFigure);
            app.DecksPanel.Title = 'Decks';
            app.DropDown_2 = uidropdown(app.DecksPanel);
            app.DropDown_2.Items = {'Player 1', 'Player 2'};
            app.DropDown_2.Value = 'Player 1';
            app.FILLButton = uibutton(app.DecksPanel, 'push');
            app.FILLButton.ButtonPushedFcn = createCallbackFcn(app, @FILLButtonPushed, true);
            app.FILLButton.Text = 'FILL';
            app.DISCARDButton = uibutton(app.DecksPanel, 'push');
            app.DISCARDButton.ButtonPushedFcn = createCallbackFcn(app, @DISCARDButtonPushed, true);
            app.DISCARDButton.Text = 'DISCARD';
            app.SORTButton = uibutton(app.DecksPanel, 'push');
            app.SORTButton.ButtonPushedFcn = createCallbackFcn(app, @SORTButtonPushed, true);
            app.SORTButton.Text = 'SORT';
            app.CANCELButton = uibutton(app.DecksPanel, 'push');
            app.CANCELButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELButtonPushed, true);
            app.CANCELButton.Text = 'CANCEL';
            app.StatusEditField_3Label = uilabel(app.DecksPanel);
            app.StatusEditField_3Label.Text = 'Status';
            app.StatusEditField_3 = uieditfield(app.DecksPanel, 'text');
            app.StatusEditField_3.Editable = 'off';
            app.StatusEditField_3.Value = 'Ready';
            app.TablePanel = uipanel(app.UIFigure);
            app.TablePanel.Title = 'Table';
            app.CLEARButton = uibutton(app.TablePanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.FILLButton_2 = uibutton(app.TablePanel, 'push');
            app.FILLButton_2.ButtonPushedFcn = createCallbackFcn(app, @FILLButton_2Pushed, true);
            app.FILLButton_2.Text = 'FILL';
            app.PathPlanningPanel = uipanel(app.UIFigure);
            app.PathPlanningPanel.Title = 'Path Planning';
            app.StatusEditFieldLabel = uilabel(app.PathPlanningPanel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.PathPlanningPanel, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Ready';
            app.NAVIGATEButton = uibutton(app.PathPlanningPanel, 'push');
            app.NAVIGATEButton.ButtonPushedFcn = createCallbackFcn(app, @NAVIGATEButtonPushed, true);
            app.NAVIGATEButton.Text = 'NAVIGATE';
            app.ResetButton_2 = uibutton(app.PathPlanningPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.StartBPEditFieldLabel = uilabel(app.PathPlanningPanel);
            app.StartBPEditFieldLabel.Text = 'Start BP';
            app.StartBPEditField = uieditfield(app.PathPlanningPanel, 'text');
            app.GoalBPEditFieldLabel = uilabel(app.PathPlanningPanel);
            app.GoalBPEditFieldLabel.Text = 'Goal BP';
            app.GoalBPEditField = uieditfield(app.PathPlanningPanel, 'text');
            app.TicTacToePanel = uipanel(app.UIFigure);
            app.TicTacToePanel.Title = 'Tic-Tac-Toe';
            app.PLAYERvPLAYERButton = uibutton(app.TicTacToePanel, 'push');
            app.PLAYERvPLAYERButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYERvPLAYERButtonPushed, true);
            app.PLAYERvPLAYERButton.Text = 'PLAYER v PLAYER';
            app.AIvPLAYERButton = uibutton(app.TicTacToePanel, 'push');
            app.AIvPLAYERButton.ButtonPushedFcn = createCallbackFcn(app, @AIvPLAYERButtonPushed, true);
            app.AIvPLAYERButton.Text = 'AI v PLAYER';
            app.StatusEditField_4Label = uilabel(app.TicTacToePanel);
            app.StatusEditField_4Label.Text = 'Status';
            app.StatusEditField_4 = uieditfield(app.TicTacToePanel, 'text');
            app.StatusEditField_4.Editable = 'off';
            app.StatusEditField_4.Value = 'Ready';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MoveGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end