classdef TableFeedGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        UpdateImageButton     matlab.ui.control.Button
        BlocksDetectedLabel   matlab.ui.control.Label
        TableCameraFeedLabel  matlab.ui.control.Label
        UITable               matlab.ui.control.Table
        ADDButton             matlab.ui.control.Button
        DELETEButton          matlab.ui.control.Button
        EDITButton            matlab.ui.control.Button
        AddOriEditFieldLabel  matlab.ui.control.Label
        AddOriEditField       matlab.ui.control.NumericEditField
        AddTypeDropDownLabel  matlab.ui.control.Label
        AddTypeDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        UpdateImageButtonPressed = 0; % Description
        EDITButtonPressed = 0;
        ADDButtonPressed = 0;
        DELETEButtonPressed = 0;
        
    end


    methods (Access = private)

        % Button pushed function: UpdateImageButton
        function UpdateImageButtonPushed(app, event)
            app.UpdateImageButtonPressed = 1;
        end

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
            app.ADDButtonPressed = 1;
        end

        % Button pushed function: DELETEButton
        function DELETEButtonPushed(app, event)
            app.DELETEButtonPressed = 1;
        end

        % Button pushed function: EDITButton
        function EDITButtonPushed(app, event)
            if(strcmp(app.EDITButton.Text,'EDIT'))
                app.EDITButton.Text = 'SUBMIT';
            else
                app.EDITButton.Text = 'EDIT';
            end
            
            app.EDITButtonPressed = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UpdateImageButton = uibutton(app.UIFigure, 'push');
            app.UpdateImageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateImageButtonPushed, true);
            app.UpdateImageButton.Text = 'Update Image';
            app.BlocksDetectedLabel = uilabel(app.UIFigure);
            app.BlocksDetectedLabel.Text = 'Blocks Detected';
            app.TableCameraFeedLabel = uilabel(app.UIFigure);
            app.TableCameraFeedLabel.Text = 'Table Camera Feed';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'BP'; 'TYPE'; 'X'; 'Y'; 'THETA'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.DELETEButton = uibutton(app.UIFigure, 'push');
            app.DELETEButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEButtonPushed, true);
            app.DELETEButton.Text = 'DELETE';
            app.EDITButton = uibutton(app.UIFigure, 'push');
            app.EDITButton.ButtonPushedFcn = createCallbackFcn(app, @EDITButtonPushed, true);
            app.EDITButton.Text = 'EDIT';
            app.AddOriEditFieldLabel = uilabel(app.UIFigure);
            app.AddOriEditFieldLabel.Text = 'Add Ori';
            app.AddOriEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddTypeDropDownLabel = uilabel(app.UIFigure);
            app.AddTypeDropDownLabel.Text = 'Add Type';
            app.AddTypeDropDown = uidropdown(app.UIFigure);
            app.AddTypeDropDown.Items = {'Shape', 'Letter'};
            app.AddTypeDropDown.Value = 'Shape';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TableFeedGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end