classdef IRB120GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IRB120ControlTab                matlab.ui.container.Tab
        JoggingPanel                    matlab.ui.container.Panel
        JogSpeedKnobLabel               matlab.ui.control.Label
        JogSpeedKnob                    matlab.ui.control.DiscreteKnob
        TabGroup2                       matlab.ui.container.TabGroup
        LinearModeTab                   matlab.ui.container.Tab
        JogFrameButtonGroup             matlab.ui.container.ButtonGroup
        BaseButton                      matlab.ui.control.RadioButton
        EndEffectorButton               matlab.ui.control.RadioButton
        YButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        YButton_3                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        JointModeTab                    matlab.ui.container.Tab
        JointButtonGroup                matlab.ui.container.ButtonGroup
        q1Button                        matlab.ui.control.ToggleButton
        q2Button                        matlab.ui.control.ToggleButton
        q3Button                        matlab.ui.control.ToggleButton
        q4Button                        matlab.ui.control.ToggleButton
        q5Button                        matlab.ui.control.ToggleButton
        q6Button                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        SetPosePanel                    matlab.ui.container.Panel
        RelativeHomeDropDownLabel       matlab.ui.control.Label
        RelativeHomeDropDown            matlab.ui.control.DropDown
        JointAnglesEditField_18         matlab.ui.control.NumericEditField
        JointAnglesEditField_19         matlab.ui.control.NumericEditField
        XLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        ZLabel_3                        matlab.ui.control.Label
        EndEffectorPositionEditField_2  matlab.ui.control.NumericEditField
        JointAnglesEditField_13         matlab.ui.control.NumericEditField
        JointAnglesEditField_14         matlab.ui.control.NumericEditField
        JointAnglesEditField_15         matlab.ui.control.NumericEditField
        JointAnglesEditField_16         matlab.ui.control.NumericEditField
        JointAnglesEditField_17         matlab.ui.control.NumericEditField
        q1Label_2                       matlab.ui.control.Label
        q2Label_2                       matlab.ui.control.Label
        q3Label_2                       matlab.ui.control.Label
        q4Label_2                       matlab.ui.control.Label
        q5Label_2                       matlab.ui.control.Label
        q6Label_2                       matlab.ui.control.Label
        JointAnglesEditField_20         matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_2  matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField    matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_3  matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_4  matlab.ui.control.NumericEditField
        QuarternionLabel                matlab.ui.control.Label
        MoveButton                      matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.Button
        InputMethodButtonGroup          matlab.ui.container.ButtonGroup
        EndEffectorPositionButton       matlab.ui.control.ToggleButton
        JointAnglesButton               matlab.ui.control.ToggleButton
        ReorientEndEffectorButton       matlab.ui.control.ToggleButton
        MovetoHomePositionButton        matlab.ui.control.StateButton
        MATLABCommandsPanel             matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        RAPIDCommandsPanel              matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        DIOsPanel                       matlab.ui.container.Panel
        EmergencyStopLampLabel          matlab.ui.control.Label
        EmergencyStopLamp               matlab.ui.control.Lamp
        LightCurtainLampLabel           matlab.ui.control.Label
        LightCurtainLamp                matlab.ui.control.Lamp
        MotorsONLampLabel               matlab.ui.control.Label
        MotorsONLamp                    matlab.ui.control.Lamp
        MotorsEnableLampLabel           matlab.ui.control.Label
        MotorsEnableLamp                matlab.ui.control.Lamp
        ExecutionErrorLampLabel         matlab.ui.control.Label
        ExecutionErrorLamp              matlab.ui.control.Lamp
        ResetIOsButton                  matlab.ui.control.Button
        ConveyorLabel                   matlab.ui.control.Label
        VacuumLabel                     matlab.ui.control.Label
        ConveyorStatusLampLabel         matlab.ui.control.Label
        ConveyorStatusLamp              matlab.ui.control.Lamp
        ConRunButton                    matlab.ui.control.StateButton
        DirectionSwitchLabel            matlab.ui.control.Label
        DirectionSwitch                 matlab.ui.control.Switch
        PumpSwitchLabel                 matlab.ui.control.Label
        PumpSwitch                      matlab.ui.control.Switch
        VacRunButton                    matlab.ui.control.StateButton
        RobotManagementPanel            matlab.ui.container.Panel
        StartUpRobotButton              matlab.ui.control.Button
        ShutdownRobotButton             matlab.ui.control.Button
        ConnectionStatusLampLabel       matlab.ui.control.Label
        ConnectionStatusLamp            matlab.ui.control.Lamp
        ReconnectButton                 matlab.ui.control.Button
        ControlModeButtonGroup          matlab.ui.container.ButtonGroup
        SimulationButton                matlab.ui.control.RadioButton
        RobotButton                     matlab.ui.control.RadioButton
        CurrentRobotStatusPanel         matlab.ui.container.Panel
        JointAnglesEditFieldLabel       matlab.ui.control.Label
        JointAnglesEditField            matlab.ui.control.NumericEditField
        JointAnglesEditField_2          matlab.ui.control.NumericEditField
        JointAnglesEditField_3          matlab.ui.control.NumericEditField
        JointAnglesEditField_4          matlab.ui.control.NumericEditField
        JointAnglesEditField_5          matlab.ui.control.NumericEditField
        JointAnglesEditField_6          matlab.ui.control.NumericEditField
        q1Label                         matlab.ui.control.Label
        q2Label                         matlab.ui.control.Label
        q3Label                         matlab.ui.control.Label
        q4Label                         matlab.ui.control.Label
        q5Label                         matlab.ui.control.Label
        q6Label                         matlab.ui.control.Label
        JointAnglesEditField_8          matlab.ui.control.NumericEditField
        JointAnglesEditField_10         matlab.ui.control.NumericEditField
        EndEffectorPositionEditFieldLabel  matlab.ui.control.Label
        EndEffectorPositionEditField    matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        JointAnglesEditField_11         matlab.ui.control.NumericEditField
        JointAnglesEditField_12         matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        EndEffectorOrientatonEditFieldLabel  matlab.ui.control.Label
        EndEffectorOrientatonEditField  matlab.ui.control.NumericEditField
        RobotReadyLampLabel             matlab.ui.control.Label
        RobotReadyLamp                  matlab.ui.control.Lamp
        TableCameraFeed                 matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        EnableCameraButton_2            matlab.ui.control.StateButton
        TextArea_5                      matlab.ui.control.TextArea
        LettersDetectedLabel_3          matlab.ui.control.Label
        CancelMovementButton_2          matlab.ui.control.Button
        EnableCameraMovementControlCheckBox_2  matlab.ui.control.CheckBox
        ConveyorCameraFeedTab_2         matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        EnableCameraButton              matlab.ui.control.StateButton
        TextArea_4                      matlab.ui.control.TextArea
        LettersDetectedLabel_2          matlab.ui.control.Label
        CancelMovementButton            matlab.ui.control.Button
        EnableCameraMovementControlCheckBox  matlab.ui.control.CheckBox
    end


    methods (Access = private)

        % Button pushed function: StartUpRobotButton
        function StartUpRobotButtonPushed(app, event)
            app.TextArea.Value = 'IRB120 Starting Up...';  
            app.TextArea_2.Value = 'IRB120 Starting Up...';  
        end

        % Button pushed function: ShutdownRobotButton
        function ShutdownRobotButtonPushed(app, event)
            app.TextArea.Value = 'IRB120 Shutting down...';  
            app.TextArea_2.Value = 'IRB120 Shutting down...';  
        end

        % Value changed function: ConRunButton
        function ConRunButtonValueChanged(app, event)
            value = app.ConRunButton.Value;
            if(value==1)
                app.TextArea.Value = 'Conveyor Running...';
                app.TextArea_2.Value = 'Conveyor Running...';
            else
                app.TextArea.Value = 'Conveyor stopped';
                app.TextArea_2.Value = 'Conveyor stopped';
            end
        end

        % Selection changed function: ControlModeButtonGroup
        function ControlModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlModeButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text,'Robot'))
                app.TextArea.Value = 'Robot Mode';
                app.TextArea_2.Value = 'Robot Mode';
            else
                app.TextArea.Value = 'Simulation Mode';
                app.TextArea_2.Value = 'Simulation Mode';
            end
        end

        % Value changed function: MovetoHomePositionButton
        function MovetoHomePositionButtonValueChanged(app, event)
            value = app.MovetoHomePositionButton.Value;
            if(value==1)
                app.JogSpeedKnob.Value = 'Fine';
                app.BaseButton.Value = 1;
                app.q1Button.Value = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IRB120ControlTab = uitab(app.TabGroup);
            app.IRB120ControlTab.Title = 'IRB120 Control';
            app.JoggingPanel = uipanel(app.IRB120ControlTab);
            app.JoggingPanel.Title = 'Jogging';
            app.JogSpeedKnobLabel = uilabel(app.JoggingPanel);
            app.JogSpeedKnobLabel.Text = 'Jog Speed';
            app.JogSpeedKnob = uiknob(app.JoggingPanel, 'discrete');
            app.JogSpeedKnob.Items = {'Fine', 'Slow', 'Medium', 'Fast'};
            app.JogSpeedKnob.Value = 'Fine';
            app.TabGroup2 = uitabgroup(app.JoggingPanel);
            app.LinearModeTab = uitab(app.TabGroup2);
            app.LinearModeTab.Title = 'Linear Mode';
            app.JogFrameButtonGroup = uibuttongroup(app.LinearModeTab);
            app.JogFrameButtonGroup.Title = 'Jog Frame';
            app.BaseButton = uiradiobutton(app.JogFrameButtonGroup);
            app.BaseButton.Text = 'Base';
            app.BaseButton.Value = true;
            app.EndEffectorButton = uiradiobutton(app.JogFrameButtonGroup);
            app.EndEffectorButton.Text = 'End Effector';
            app.YButton = uibutton(app.LinearModeTab, 'push');
            app.YButton.Text = 'Y-';
            app.XButton_2 = uibutton(app.LinearModeTab, 'push');
            app.XButton_2.Text = 'X-';
            app.YButton_3 = uibutton(app.LinearModeTab, 'push');
            app.YButton_3.Text = 'Y+';
            app.XButton = uibutton(app.LinearModeTab, 'push');
            app.XButton.Text = 'X+';
            app.ZButton_2 = uibutton(app.LinearModeTab, 'push');
            app.ZButton_2.Text = 'Z-';
            app.ZButton = uibutton(app.LinearModeTab, 'push');
            app.ZButton.Text = 'Z+';
            app.JointModeTab = uitab(app.TabGroup2);
            app.JointModeTab.Title = 'Joint Mode';
            app.JointButtonGroup = uibuttongroup(app.JointModeTab);
            app.JointButtonGroup.Title = 'Joint';
            app.q1Button = uitogglebutton(app.JointButtonGroup);
            app.q1Button.Text = 'q1';
            app.q1Button.Value = true;
            app.q2Button = uitogglebutton(app.JointButtonGroup);
            app.q2Button.Text = 'q2';
            app.q3Button = uitogglebutton(app.JointButtonGroup);
            app.q3Button.Text = 'q3';
            app.q4Button = uitogglebutton(app.JointButtonGroup);
            app.q4Button.Text = 'q4';
            app.q5Button = uitogglebutton(app.JointButtonGroup);
            app.q5Button.Text = 'q5';
            app.q6Button = uitogglebutton(app.JointButtonGroup);
            app.q6Button.Text = 'q6';
            app.Button = uibutton(app.JointModeTab, 'push');
            app.Button.Text = '+';
            app.Button_4 = uibutton(app.JointModeTab, 'push');
            app.Button_4.Text = '-';
            app.SetPosePanel = uipanel(app.JoggingPanel);
            app.SetPosePanel.Title = 'Set Pose';
            app.RelativeHomeDropDownLabel = uilabel(app.SetPosePanel);
            app.RelativeHomeDropDownLabel.Text = 'Relative Home';
            app.RelativeHomeDropDown = uidropdown(app.SetPosePanel);
            app.RelativeHomeDropDown.Items = {'Table', 'Conveyor'};
            app.RelativeHomeDropDown.Value = 'Table';
            app.JointAnglesEditField_18 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_19 = uieditfield(app.SetPosePanel, 'numeric');
            app.XLabel_3 = uilabel(app.SetPosePanel);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.SetPosePanel);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.SetPosePanel);
            app.ZLabel_3.Text = 'Z';
            app.EndEffectorPositionEditField_2 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_13 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_14 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_15 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_16 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_17 = uieditfield(app.SetPosePanel, 'numeric');
            app.q1Label_2 = uilabel(app.SetPosePanel);
            app.q1Label_2.Text = 'q1';
            app.q2Label_2 = uilabel(app.SetPosePanel);
            app.q2Label_2.Text = 'q2';
            app.q3Label_2 = uilabel(app.SetPosePanel);
            app.q3Label_2.Text = 'q3';
            app.q4Label_2 = uilabel(app.SetPosePanel);
            app.q4Label_2.Text = 'q4';
            app.q5Label_2 = uilabel(app.SetPosePanel);
            app.q5Label_2.Text = 'q5';
            app.q6Label_2 = uilabel(app.SetPosePanel);
            app.q6Label_2.Text = 'q6';
            app.JointAnglesEditField_20 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_2 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_3 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_4 = uieditfield(app.SetPosePanel, 'numeric');
            app.QuarternionLabel = uilabel(app.SetPosePanel);
            app.QuarternionLabel.Text = 'Quarternion';
            app.MoveButton = uibutton(app.SetPosePanel, 'state');
            app.MoveButton.Text = 'Move';
            app.ResetButton = uibutton(app.SetPosePanel, 'push');
            app.ResetButton.Text = 'Reset ';
            app.InputMethodButtonGroup = uibuttongroup(app.SetPosePanel);
            app.InputMethodButtonGroup.Title = 'Input Method';
            app.EndEffectorPositionButton = uitogglebutton(app.InputMethodButtonGroup);
            app.EndEffectorPositionButton.Text = 'End Effector Position';
            app.EndEffectorPositionButton.Value = true;
            app.JointAnglesButton = uitogglebutton(app.InputMethodButtonGroup);
            app.JointAnglesButton.Text = 'Joint Angles';
            app.ReorientEndEffectorButton = uitogglebutton(app.InputMethodButtonGroup);
            app.ReorientEndEffectorButton.Text = 'Reorient End Effector';
            app.MovetoHomePositionButton = uibutton(app.JoggingPanel, 'state');
            app.MovetoHomePositionButton.ValueChangedFcn = createCallbackFcn(app, @MovetoHomePositionButtonValueChanged, true);
            app.MovetoHomePositionButton.Text = {'Move to Home'; ' Position'};
            app.MATLABCommandsPanel = uipanel(app.IRB120ControlTab);
            app.MATLABCommandsPanel.Title = 'MATLAB Commands';
            app.TextArea = uitextarea(app.MATLABCommandsPanel);
            app.RAPIDCommandsPanel = uipanel(app.IRB120ControlTab);
            app.RAPIDCommandsPanel.Title = 'RAPID Commands';
            app.TextArea_2 = uitextarea(app.RAPIDCommandsPanel);
            app.DIOsPanel = uipanel(app.IRB120ControlTab);
            app.DIOsPanel.Title = 'DIOs';
            app.EmergencyStopLampLabel = uilabel(app.DIOsPanel);
            app.EmergencyStopLampLabel.Text = 'Emergency Stop';
            app.EmergencyStopLamp = uilamp(app.DIOsPanel);
            app.LightCurtainLampLabel = uilabel(app.DIOsPanel);
            app.LightCurtainLampLabel.Text = 'Light Curtain';
            app.LightCurtainLamp = uilamp(app.DIOsPanel);
            app.MotorsONLampLabel = uilabel(app.DIOsPanel);
            app.MotorsONLampLabel.Text = 'Motors ON';
            app.MotorsONLamp = uilamp(app.DIOsPanel);
            app.MotorsEnableLampLabel = uilabel(app.DIOsPanel);
            app.MotorsEnableLampLabel.Text = 'Motors Enable';
            app.MotorsEnableLamp = uilamp(app.DIOsPanel);
            app.ExecutionErrorLampLabel = uilabel(app.DIOsPanel);
            app.ExecutionErrorLampLabel.Text = 'Execution Error';
            app.ExecutionErrorLamp = uilamp(app.DIOsPanel);
            app.ResetIOsButton = uibutton(app.DIOsPanel, 'push');
            app.ResetIOsButton.Text = 'Reset IOs';
            app.ConveyorLabel = uilabel(app.DIOsPanel);
            app.ConveyorLabel.Text = 'Conveyor';
            app.VacuumLabel = uilabel(app.DIOsPanel);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConveyorStatusLampLabel = uilabel(app.DIOsPanel);
            app.ConveyorStatusLampLabel.Text = 'Conveyor Status';
            app.ConveyorStatusLamp = uilamp(app.DIOsPanel);
            app.ConRunButton = uibutton(app.DIOsPanel, 'state');
            app.ConRunButton.ValueChangedFcn = createCallbackFcn(app, @ConRunButtonValueChanged, true);
            app.ConRunButton.Text = 'ConRun';
            app.DirectionSwitchLabel = uilabel(app.DIOsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.DirectionSwitch.Items = {'Backward', 'Forwards'};
            app.DirectionSwitch.Value = 'Backward';
            app.PumpSwitchLabel = uilabel(app.DIOsPanel);
            app.PumpSwitchLabel.Text = 'Pump';
            app.PumpSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.VacRunButton = uibutton(app.DIOsPanel, 'state');
            app.VacRunButton.Text = 'VacRun';
            app.RobotManagementPanel = uipanel(app.IRB120ControlTab);
            app.RobotManagementPanel.Title = 'Robot Management';
            app.StartUpRobotButton = uibutton(app.RobotManagementPanel, 'push');
            app.StartUpRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartUpRobotButtonPushed, true);
            app.StartUpRobotButton.Text = 'Start Up Robot';
            app.ShutdownRobotButton = uibutton(app.RobotManagementPanel, 'push');
            app.ShutdownRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownRobotButtonPushed, true);
            app.ShutdownRobotButton.Text = 'Shutdown Robot';
            app.ConnectionStatusLampLabel = uilabel(app.RobotManagementPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RobotManagementPanel);
            app.ReconnectButton = uibutton(app.RobotManagementPanel, 'push');
            app.ReconnectButton.Text = 'Reconnect';
            app.ControlModeButtonGroup = uibuttongroup(app.RobotManagementPanel);
            app.ControlModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlModeButtonGroupSelectionChanged, true);
            app.ControlModeButtonGroup.Title = 'Control Mode';
            app.SimulationButton = uiradiobutton(app.ControlModeButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.RobotButton = uiradiobutton(app.ControlModeButtonGroup);
            app.RobotButton.Text = 'Robot';
            app.CurrentRobotStatusPanel = uipanel(app.IRB120ControlTab);
            app.CurrentRobotStatusPanel.Title = 'Current Robot Status';
            app.JointAnglesEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.JointAnglesEditFieldLabel.Text = 'Joint Angles';
            app.JointAnglesEditField = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_2 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_3 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_4 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_5 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_6 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.q1Label = uilabel(app.CurrentRobotStatusPanel);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.CurrentRobotStatusPanel);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.CurrentRobotStatusPanel);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.CurrentRobotStatusPanel);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.CurrentRobotStatusPanel);
            app.q5Label.Text = 'q5';
            app.q6Label = uilabel(app.CurrentRobotStatusPanel);
            app.q6Label.Text = 'q6';
            app.JointAnglesEditField_8 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_10 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EndEffectorPositionEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.EndEffectorPositionEditFieldLabel.Text = 'End Effector Position ';
            app.EndEffectorPositionEditField = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.XLabel = uilabel(app.CurrentRobotStatusPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.CurrentRobotStatusPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.CurrentRobotStatusPanel);
            app.ZLabel.Text = 'Z';
            app.JointAnglesEditField_11 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_12 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.XLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.XLabel_2.Text = 'X°';
            app.YLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.YLabel_2.Text = 'Y°';
            app.ZLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.ZLabel_2.Text = 'Z°';
            app.EndEffectorOrientatonEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.EndEffectorOrientatonEditFieldLabel.Text = 'End Effector Orientaton ';
            app.EndEffectorOrientatonEditField = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.RobotReadyLampLabel = uilabel(app.CurrentRobotStatusPanel);
            app.RobotReadyLampLabel.Text = 'Robot Ready';
            app.RobotReadyLamp = uilamp(app.CurrentRobotStatusPanel);
            app.TableCameraFeed = uitab(app.TabGroup);
            app.TableCameraFeed.Title = 'Table Camera Feed';
            app.UIAxes = uiaxes(app.TableCameraFeed);
            title(app.UIAxes, 'Table Camera Feed')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EnableCameraButton_2 = uibutton(app.TableCameraFeed, 'state');
            app.EnableCameraButton_2.Text = 'Enable Camera';
            app.TextArea_5 = uitextarea(app.TableCameraFeed);
            app.LettersDetectedLabel_3 = uilabel(app.TableCameraFeed);
            app.LettersDetectedLabel_3.Text = 'Letters Detected';
            app.CancelMovementButton_2 = uibutton(app.TableCameraFeed, 'push');
            app.CancelMovementButton_2.Text = 'Cancel Movement';
            app.EnableCameraMovementControlCheckBox_2 = uicheckbox(app.TableCameraFeed);
            app.EnableCameraMovementControlCheckBox_2.Text = 'Enable Camera Movement Control';
            app.ConveyorCameraFeedTab_2 = uitab(app.TabGroup);
            app.ConveyorCameraFeedTab_2.Title = 'Conveyor Camera Feed';
            app.UIAxes_2 = uiaxes(app.ConveyorCameraFeedTab_2);
            title(app.UIAxes_2, 'Conveyor Camera Feed')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.EnableCameraButton = uibutton(app.ConveyorCameraFeedTab_2, 'state');
            app.EnableCameraButton.Text = 'Enable Camera';
            app.TextArea_4 = uitextarea(app.ConveyorCameraFeedTab_2);
            app.LettersDetectedLabel_2 = uilabel(app.ConveyorCameraFeedTab_2);
            app.LettersDetectedLabel_2.Text = 'Letters Detected';
            app.CancelMovementButton = uibutton(app.ConveyorCameraFeedTab_2, 'push');
            app.CancelMovementButton.Text = 'Cancel Movement';
            app.EnableCameraMovementControlCheckBox = uicheckbox(app.ConveyorCameraFeedTab_2);
            app.EnableCameraMovementControlCheckBox.Text = 'Enable Camera Movement Control';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IRB120GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end