classdef TTTGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GameStateTextAreaLabel   matlab.ui.control.Label
        GameStateTextArea        matlab.ui.control.TextArea
        PlayerTurnTextAreaLabel  matlab.ui.control.Label
        PlayerTurnTextArea       matlab.ui.control.TextArea
        GameGridLabel            matlab.ui.control.Label
        Cell1Button              matlab.ui.control.Button
        Cell2Button              matlab.ui.control.Button
        Cell3Button              matlab.ui.control.Button
        Cell4Button              matlab.ui.control.Button
        Cell7Button              matlab.ui.control.Button
        Cell5Button              matlab.ui.control.Button
        Cell6Button              matlab.ui.control.Button
        Cell8Button              matlab.ui.control.Button
        Cell9Button              matlab.ui.control.Button
        PlayerOneOLabel          matlab.ui.control.Label
        GameDetailsLabel         matlab.ui.control.Label
        GameOptionsLabel         matlab.ui.control.Label
        PauseGameButton_2        matlab.ui.control.Button
        ResumeGameButton         matlab.ui.control.Button
        EndGameButton            matlab.ui.control.Button
        GameErrorsTextAreaLabel  matlab.ui.control.Label
        GameErrorsTextArea       matlab.ui.control.TextArea
        Lamp                     matlab.ui.control.Lamp
        Lamp_2                   matlab.ui.control.Lamp
        Lamp_3                   matlab.ui.control.Lamp
        Lamp_4                   matlab.ui.control.Lamp
        Lamp_5                   matlab.ui.control.Lamp
        Lamp_6                   matlab.ui.control.Lamp
        Lamp_7                   matlab.ui.control.Lamp
        Lamp_8                   matlab.ui.control.Lamp
        Lamp_9                   matlab.ui.control.Lamp
        PlayerTwoXLabel          matlab.ui.control.Label
    end

    properties (Access = public)
        PauseGameButtonVal = 0;
        ResumeGameButtonVal = 0;
        EndGameButtonVal = 0;
        
        EndGameButtonPressed = 0;
        PauseGameButtonPressed = 0;
        ResumeGameButtonPressed = 0;
        
        % if cell is pressed
        CellOneVal = 0;
        CellTwoVal = 0;
        CellThreeVal = 0;
        CellFourVal = 0;
        CellFiveVal = 0;
        CellSixVal = 0;
        CellSevenVal = 0;
        CellEightVal = 0;
        CellNineVal = 0;
        
        % work out if cell is O or X. O = 1, X = 2.
        CellOneNum = 0;
        CellTwoNum = 0;
        CellThreeNum = 0;
        CellFourNum = 0;
        CellFiveNum = 0;
        CellSixNum = 0;
        CellSevenNum = 0;
        CellEightNum = 0;
        CellNineNum = 0;
        
        Player1 = 'O';
        Player2 = 'X';
        
        counter = 0;
    end


    methods (Access = private)

        % Button pushed function: PauseGameButton_2
        function PauseGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Paused...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.PauseGameButtonVal = 1;
            app.ResumeGameButtonVal = 0;
            
            app.PauseGameButtonPressed = 1;
        end

        % Button pushed function: ResumeGameButton
        function ResumeGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Running...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.ResumeGameButtonVal = 1;
            app.PauseGameButtonVal = 0;
            
            app.ResumeGameButtonPressed = 1;
        end

        % Button pushed function: EndGameButton
        function EndGameButtonPushed(app, event)
            if(app.EndGameButtonVal == 0)
                app.GameStateTextArea.Value = 'Game Ending...';
            elseif (app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
            end
            app.EndGameButtonVal = 1;
            app.EndGameButtonPressed = 1;
            %app.delete;
        end

        % Button pushed function: Cell1Button
        function Cell1ButtonPushed(app, event)
            if(app.CellOneVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!'; 
            elseif(app.CellOneVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';        
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even, O was selected
                    app.Cell1Button.Text = app.Player1;
                    app.Cell1Button.FontSize = 28;
                    app.Cell1Button.FontWeight = 'bold';
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                    app.CellOneNum = 1;
                else
                    % counter is odd, X is selected
                    app.Cell1Button.Text = app.Player2;
                    app.Cell1Button.FontSize = 28;
                    app.Cell1Button.FontWeight = 'bold';
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00]; 
                    app.CellOneNum = 2;
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even, O was selected
                    app.Cell1Button.Text = app.Player1;
                    app.Cell1Button.FontSize = 28;
                    app.Cell1Button.FontWeight = 'bold';
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                    app.CellOneNum = 1;
                else
                    % counter is odd, X is selected
                    app.Cell1Button.Text = app.Player2;
                    app.Cell1Button.FontSize = 28;
                    app.Cell1Button.FontWeight = 'bold';
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00]; 
                    app.CellOneNum = 2;
                end

                app.counter = app.counter + 1;
                
                app.CellOneVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp.Enable = 'off';

                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end   
            end
        end

        % Button pushed function: Cell2Button
        function Cell2ButtonPushed(app, event)
            if(app.CellTwoVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellTwoVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellOneVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell2Button.Text = app.Player1;
                    app.Cell2Button.FontSize = 28;
                    app.Cell2Button.FontWeight = 'bold';
                    app.CellTwoNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell2Button.Text = app.Player2;
                    app.Cell2Button.FontSize = 28;
                    app.Cell2Button.FontWeight = 'bold';
                    app.CellTwoNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;    
            else 
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell2Button.Text = app.Player1;
                    app.Cell2Button.FontSize = 28;
                    app.Cell2Button.FontWeight = 'bold';
                    app.CellTwoNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell2Button.Text = app.Player2;
                    app.Cell2Button.FontSize = 28;
                    app.Cell2Button.FontWeight = 'bold';
                    app.CellTwoNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellTwoVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_2.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell3Button
        function Cell3ButtonPushed(app, event)
            if(app.CellThreeVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellThreeVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellOneVal == 1 && app.CellTwoVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell3Button.Text = app.Player1;
                    app.Cell3Button.FontSize = 28;
                    app.Cell3Button.FontWeight = 'bold';
                    app.CellThreeNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell3Button.Text = app.Player2;
                    app.Cell3Button.FontSize = 28;
                    app.Cell3Button.FontWeight = 'bold';
                    app.CellThreeNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell3Button.Text = app.Player1;
                    app.Cell3Button.FontSize = 28;
                    app.Cell3Button.FontWeight = 'bold';
                    app.CellThreeNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell3Button.Text = app.Player2;
                    app.Cell3Button.FontSize = 28;
                    app.Cell3Button.FontWeight = 'bold';
                    app.CellThreeNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellThreeVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_3.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell4Button
        function Cell4ButtonPushed(app, event)
            if(app.CellFourVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellFourVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellOneVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell4Button.Text = app.Player1;
                    app.Cell4Button.FontSize = 28;
                    app.Cell4Button.FontWeight = 'bold';
                    app.CellFourNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell4Button.Text = app.Player2;
                    app.Cell4Button.FontSize = 28;
                    app.Cell4Button.FontWeight = 'bold';
                    app.CellFourNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell4Button.Text = app.Player1;
                    app.Cell4Button.FontSize = 28;
                    app.Cell4Button.FontWeight = 'bold';
                    app.CellFourNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell4Button.Text = app.Player2;
                    app.Cell4Button.FontSize = 28;
                    app.Cell4Button.FontWeight = 'bold';
                    app.CellFourNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellFourVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_4.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell5Button
        function Cell5ButtonPushed(app, event)
            if(app.CellFiveVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellFiveVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellOneVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell5Button.Text = app.Player1;
                    app.Cell5Button.FontSize = 28;
                    app.Cell5Button.FontWeight = 'bold';
                    app.CellFiveNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell5Button.Text = app.Player2;
                    app.Cell5Button.FontSize = 28;
                    app.Cell5Button.FontWeight = 'bold';
                    app.CellFiveNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell5Button.Text = app.Player1;
                    app.Cell5Button.FontSize = 28;
                    app.Cell5Button.FontWeight = 'bold';
                    app.CellFiveNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell5Button.Text = app.Player2;
                    app.Cell5Button.FontSize = 28;
                    app.Cell5Button.FontWeight = 'bold';
                    app.CellFiveNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellFiveVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_5.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell6Button
        function Cell6ButtonPushed(app, event)
            if(app.CellSixVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellSixVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellOneVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell6Button.Text = app.Player1;
                    app.Cell6Button.FontSize = 28;
                    app.Cell6Button.FontWeight = 'bold';
                    app.CellSixNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell6Button.Text = app.Player2;
                    app.Cell6Button.FontSize = 28;
                    app.Cell6Button.FontWeight = 'bold';
                    app.CellSixNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell6Button.Text = app.Player1;
                    app.Cell6Button.FontSize = 28;
                    app.Cell6Button.FontWeight = 'bold';
                    app.CellSixNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell6Button.Text = app.Player2;
                    app.Cell6Button.FontSize = 28;
                    app.Cell6Button.FontWeight = 'bold';
                    app.CellSixNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellSixVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_6.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell7Button
        function Cell7ButtonPushed(app, event)
            if(app.CellSevenVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellSevenVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellOneVal == 1 && app.CellEightVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell7Button.Text = app.Player1;
                    app.Cell7Button.FontSize = 28;
                    app.Cell7Button.FontWeight = 'bold';
                    app.CellSevenNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell7Button.Text = app.Player2;
                    app.Cell7Button.FontSize = 28;
                    app.Cell7Button.FontWeight = 'bold';
                    app.CellSevenNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell7Button.Text = app.Player1;
                    app.Cell7Button.FontSize = 28;
                    app.Cell7Button.FontWeight = 'bold';
                    app.CellSevenNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell7Button.Text = app.Player2;
                    app.Cell7Button.FontSize = 28;
                    app.Cell7Button.FontWeight = 'bold';
                    app.CellSevenNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellSevenVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_7.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
               % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell8Button
        function Cell8ButtonPushed(app, event)
            if(app.CellEightVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellEightVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellOneVal == 1 && app.CellNineVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell8Button.Text = app.Player1;
                    app.Cell8Button.FontSize = 28;
                    app.Cell8Button.FontWeight = 'bold';
                    app.CellEightNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell8Button.Text = app.Player2;
                    app.Cell8Button.FontSize = 28;
                    app.Cell8Button.FontWeight = 'bold';
                    app.CellEightNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell8Button.Text = app.Player1;
                    app.Cell8Button.FontSize = 28;
                    app.Cell8Button.FontWeight = 'bold';
                    app.CellEightNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell8Button.Text = app.Player2;
                    app.Cell8Button.FontSize = 28;
                    app.Cell8Button.FontWeight = 'bold';
                    app.CellEightNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellEightVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_8.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end

        % Button pushed function: Cell9Button
        function Cell9ButtonPushed(app, event)
            if(app.CellNineVal == 1 && app.EndGameButtonVal == 0)
                app.GameErrorsTextArea.Value = 'This has already been selected! Please choose another box.';
            elseif(app.EndGameButtonVal == 1)
                app.GameStateTextArea.Value = 'Game has Ended...';
                app.GameErrorsTextArea.Value = 'No Errors!';
            elseif(app.CellNineVal == 0 && app.PauseGameButtonVal ==1)
                app.GameErrorsTextArea.Value = 'Cannot make move! Game is paused.';
            elseif(app.CellTwoVal == 1 && app.CellThreeVal == 1 && app.CellFourVal == 1 && app.CellFiveVal == 1 && app.CellSixVal == 1 && app.CellSevenVal == 1 && app.CellEightVal == 1 && app.CellOneVal == 1)
                app.GameStateTextArea.Value = 'Game has Drawn!';
                app.GameErrorsTextArea.Value = 'No Errors!';
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell9Button.Text = app.Player1;
                    app.Cell9Button.FontSize = 28;
                    app.Cell9Button.FontWeight = 'bold';
                    app.CellNineNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell9Button.Text = app.Player2;
                    app.Cell9Button.FontSize = 28;
                    app.Cell9Button.FontWeight = 'bold';
                    app.CellNineNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                app.EndGameButtonVal = 1;
            else
                
                if mod(app.counter, 2) == 0
                    % counter is even
                    app.Cell9Button.Text = app.Player1;
                    app.Cell9Button.FontSize = 28;
                    app.Cell9Button.FontWeight = 'bold';
                    app.CellNineNum = 1;
                    app.PlayerTurnTextArea.Value = 'Player Two Turn';
                    app.PlayerTurnTextArea.FontColor = [0.00, 0.45, 0.74];
                else
                    % counter is odd
                    app.Cell9Button.Text = app.Player2;
                    app.Cell9Button.FontSize = 28;
                    app.Cell9Button.FontWeight = 'bold';
                    app.CellNineNum = 2;
                    app.PlayerTurnTextArea.Value = 'Player One Turn';
                    app.PlayerTurnTextArea.FontColor = [1.00, 0.00, 0.00];
                end
                
                app.counter = app.counter + 1;
                
                app.CellNineVal = 1;
                app.GameErrorsTextArea.Value = 'No Errors!';
                app.Lamp_9.Enable = 'off';
                
                res1 = app.CellOneNum;
                res2 = app.CellTwoNum;
                res3 = app.CellThreeNum;
                res4 = app.CellFourNum;
                res5 = app.CellFiveNum;
                res6 = app.CellSixNum;
                res7 = app.CellSevenNum;
                res8 = app.CellEightNum;
                res9 = app.CellNineNum;
                
                % 1st row
                if(res1 * res2 * res3 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res2 + res3 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd row
                elseif (res4 * res5 * res6 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res4 + res5 + res6 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd row
                elseif (res7 * res8 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res7 + res8 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                    
                % 1st col
                elseif (res1 * res4 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res4 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 2nd col 
                elseif (res2 * res5 * res8 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res2 + res5 + res8 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % 3rd col
                elseif (res3 * res6 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res6 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
  
                % diagonal 1
                elseif (res1 * res5 * res9 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res1 + res5 + res9 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!'; 
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                
                % diagonal 2
                elseif (res3 * res5 * res7 == 1)
                    app.GameStateTextArea.Value = 'PLAYER 1 WINS!';
                    app.GameStateTextArea.BackgroundColor = [1.00, 0, 0];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                elseif (res3 + res5 + res7 == 6)
                    app.GameStateTextArea.Value = 'PLAYER 2 WINS!';
                    app.GameStateTextArea.BackgroundColor = [0, 0.45, 0.74];
                    app.PlayerTurnTextArea.FontColor = [0,0,0];
                    app.PlayerTurnTextArea.Value = 'Game Over!';
                    app.GameErrorsTextArea.Value = 'Game Over!';
                    app.EndGameButtonVal = 1;
                end       
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GameStateTextAreaLabel = uilabel(app.UIFigure);
            app.GameStateTextAreaLabel.Text = 'Game State';
            app.GameStateTextArea = uitextarea(app.UIFigure);
            app.GameStateTextArea.Value = {'Game Running...'};
            app.PlayerTurnTextAreaLabel = uilabel(app.UIFigure);
            app.PlayerTurnTextAreaLabel.Text = 'Player Turn';
            app.PlayerTurnTextArea = uitextarea(app.UIFigure);
            app.PlayerTurnTextArea.Value = {'Player One Turn'};
            app.GameGridLabel = uilabel(app.UIFigure);
            app.GameGridLabel.Text = 'Game Grid';
            app.Cell1Button = uibutton(app.UIFigure, 'push');
            app.Cell1Button.ButtonPushedFcn = createCallbackFcn(app, @Cell1ButtonPushed, true);
            app.Cell1Button.Text = 'Cell 1';
            app.Cell2Button = uibutton(app.UIFigure, 'push');
            app.Cell2Button.ButtonPushedFcn = createCallbackFcn(app, @Cell2ButtonPushed, true);
            app.Cell2Button.Text = 'Cell 2';
            app.Cell3Button = uibutton(app.UIFigure, 'push');
            app.Cell3Button.ButtonPushedFcn = createCallbackFcn(app, @Cell3ButtonPushed, true);
            app.Cell3Button.Text = 'Cell 3';
            app.Cell4Button = uibutton(app.UIFigure, 'push');
            app.Cell4Button.ButtonPushedFcn = createCallbackFcn(app, @Cell4ButtonPushed, true);
            app.Cell4Button.Text = 'Cell 4';
            app.Cell7Button = uibutton(app.UIFigure, 'push');
            app.Cell7Button.ButtonPushedFcn = createCallbackFcn(app, @Cell7ButtonPushed, true);
            app.Cell7Button.Text = 'Cell 7';
            app.Cell5Button = uibutton(app.UIFigure, 'push');
            app.Cell5Button.ButtonPushedFcn = createCallbackFcn(app, @Cell5ButtonPushed, true);
            app.Cell5Button.Text = 'Cell 5';
            app.Cell6Button = uibutton(app.UIFigure, 'push');
            app.Cell6Button.ButtonPushedFcn = createCallbackFcn(app, @Cell6ButtonPushed, true);
            app.Cell6Button.Text = 'Cell 6';
            app.Cell8Button = uibutton(app.UIFigure, 'push');
            app.Cell8Button.ButtonPushedFcn = createCallbackFcn(app, @Cell8ButtonPushed, true);
            app.Cell8Button.Text = 'Cell 8';
            app.Cell9Button = uibutton(app.UIFigure, 'push');
            app.Cell9Button.ButtonPushedFcn = createCallbackFcn(app, @Cell9ButtonPushed, true);
            app.Cell9Button.Text = 'Cell 9';
            app.PlayerOneOLabel = uilabel(app.UIFigure);
            app.PlayerOneOLabel.Text = 'Player One = O';
            app.GameDetailsLabel = uilabel(app.UIFigure);
            app.GameDetailsLabel.Text = 'Game Details';
            app.GameOptionsLabel = uilabel(app.UIFigure);
            app.GameOptionsLabel.Text = 'Game Options';
            app.PauseGameButton_2 = uibutton(app.UIFigure, 'push');
            app.PauseGameButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseGameButtonPushed, true);
            app.PauseGameButton_2.Text = 'Pause Game';
            app.ResumeGameButton = uibutton(app.UIFigure, 'push');
            app.ResumeGameButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeGameButtonPushed, true);
            app.ResumeGameButton.Text = 'Resume Game';
            app.EndGameButton = uibutton(app.UIFigure, 'push');
            app.EndGameButton.ButtonPushedFcn = createCallbackFcn(app, @EndGameButtonPushed, true);
            app.EndGameButton.Text = 'End Game';
            app.GameErrorsTextAreaLabel = uilabel(app.UIFigure);
            app.GameErrorsTextAreaLabel.Text = 'Game Errors';
            app.GameErrorsTextArea = uitextarea(app.UIFigure);
            app.GameErrorsTextArea.Value = {'No Errors!'};
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.PlayerTwoXLabel = uilabel(app.UIFigure);
            app.PlayerTwoXLabel.Text = 'Player Two = X';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TTTGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end