classdef IRB120GUI_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IRB120ControlTab                matlab.ui.container.Tab
        JoggingPanel                    matlab.ui.container.Panel
        JogSpeedKnobLabel               matlab.ui.control.Label
        JogSpeedKnob                    matlab.ui.control.DiscreteKnob
        TabGroup2                       matlab.ui.container.TabGroup
        LinearModeTab                   matlab.ui.container.Tab
        JogFrameButtonGroup             matlab.ui.container.ButtonGroup
        BaseButton                      matlab.ui.control.RadioButton
        EndEffectorButton               matlab.ui.control.RadioButton
        YButton                         matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        YButton_3                       matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        JointModeTab                    matlab.ui.container.Tab
        JointButtonGroup                matlab.ui.container.ButtonGroup
        q1Button                        matlab.ui.control.ToggleButton
        q2Button                        matlab.ui.control.ToggleButton
        q3Button                        matlab.ui.control.ToggleButton
        q4Button                        matlab.ui.control.ToggleButton
        q5Button                        matlab.ui.control.ToggleButton
        q6Button                        matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        SetPosePanel                    matlab.ui.container.Panel
        RelativeHomeDropDownLabel       matlab.ui.control.Label
        RelativeHomeDropDown            matlab.ui.control.DropDown
        EndEffectorPositionEditField_Y  matlab.ui.control.NumericEditField
        EndEffectorPositionEditField_Z  matlab.ui.control.NumericEditField
        XLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        ZLabel_3                        matlab.ui.control.Label
        EndEffectorPositionEditField_X  matlab.ui.control.NumericEditField
        JointAnglesEditField_q2         matlab.ui.control.NumericEditField
        JointAnglesEditField_q3         matlab.ui.control.NumericEditField
        JointAnglesEditField_q4         matlab.ui.control.NumericEditField
        JointAnglesEditField_q5         matlab.ui.control.NumericEditField
        JointAnglesEditField_q6         matlab.ui.control.NumericEditField
        q1Label_2                       matlab.ui.control.Label
        q2Label_2                       matlab.ui.control.Label
        q3Label_2                       matlab.ui.control.Label
        q4Label_2                       matlab.ui.control.Label
        q5Label_2                       matlab.ui.control.Label
        q6Label_2                       matlab.ui.control.Label
        JointAnglesEditField_q1         matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_2  matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_3  matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField    matlab.ui.control.NumericEditField
        ReorientEndEffectorEditField_4  matlab.ui.control.NumericEditField
        QuarternionLabel                matlab.ui.control.Label
        MoveButton                      matlab.ui.control.StateButton
        ResetButton                     matlab.ui.control.Button
        InputMethodButtonGroup          matlab.ui.container.ButtonGroup
        EndEffectorPositionButton       matlab.ui.control.ToggleButton
        JointAnglesButton               matlab.ui.control.ToggleButton
        ReorientEndEffectorButton       matlab.ui.control.ToggleButton
        MovetoHomePositionButton        matlab.ui.control.StateButton
        MATLABCommandsPanel             matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        RAPIDCommandsPanel              matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        DIOsPanel                       matlab.ui.container.Panel
        EmergencyStopLampLabel          matlab.ui.control.Label
        EmergencyStopLamp               matlab.ui.control.Lamp
        LightCurtainLampLabel           matlab.ui.control.Label
        LightCurtainLamp                matlab.ui.control.Lamp
        MotorsONLampLabel               matlab.ui.control.Label
        MotorsONLamp                    matlab.ui.control.Lamp
        MotorsEnableLampLabel           matlab.ui.control.Label
        MotorsEnableLamp                matlab.ui.control.Lamp
        ExecutionErrorLampLabel         matlab.ui.control.Label
        ExecutionErrorLamp              matlab.ui.control.Lamp
        ResetIOsButton                  matlab.ui.control.Button
        ConveyorLabel                   matlab.ui.control.Label
        VacuumLabel                     matlab.ui.control.Label
        ConveyorStatusLampLabel         matlab.ui.control.Label
        ConveyorStatusLamp              matlab.ui.control.Lamp
        ConRunButton                    matlab.ui.control.StateButton
        DirectionSwitchLabel            matlab.ui.control.Label
        DirectionSwitch                 matlab.ui.control.Switch
        PumpSwitchLabel                 matlab.ui.control.Label
        PumpSwitch                      matlab.ui.control.Switch
        VacRunButton                    matlab.ui.control.StateButton
        DirectionLampLabel              matlab.ui.control.Label
        DirectionLamp                   matlab.ui.control.Lamp
        ConRunLampLabel                 matlab.ui.control.Label
        ConRunLamp                      matlab.ui.control.Lamp
        PumpLampLabel                   matlab.ui.control.Label
        PumpLamp                        matlab.ui.control.Lamp
        VacSolLampLabel                 matlab.ui.control.Label
        VacSolLamp                      matlab.ui.control.Lamp
        MotionSupervisionLampLabel      matlab.ui.control.Label
        MotionSupervisionLamp           matlab.ui.control.Lamp
        RobotManagementPanel            matlab.ui.container.Panel
        StartUpRobotButton              matlab.ui.control.Button
        ShutdownRobotButton             matlab.ui.control.Button
        ConnectionStatusLampLabel       matlab.ui.control.Label
        ConnectionStatusLamp            matlab.ui.control.Lamp
        ReconnectButton                 matlab.ui.control.Button
        ControlModeButtonGroup          matlab.ui.container.ButtonGroup
        SimulationButton                matlab.ui.control.RadioButton
        RobotButton                     matlab.ui.control.RadioButton
        CurrentRobotStatusPanel         matlab.ui.container.Panel
        JointAnglesEditFieldLabel       matlab.ui.control.Label
        JointAnglesEditField            matlab.ui.control.NumericEditField
        JointAnglesEditField_2          matlab.ui.control.NumericEditField
        JointAnglesEditField_3          matlab.ui.control.NumericEditField
        JointAnglesEditField_4          matlab.ui.control.NumericEditField
        JointAnglesEditField_5          matlab.ui.control.NumericEditField
        JointAnglesEditField_6          matlab.ui.control.NumericEditField
        q1Label                         matlab.ui.control.Label
        q2Label                         matlab.ui.control.Label
        q3Label                         matlab.ui.control.Label
        q4Label                         matlab.ui.control.Label
        q5Label                         matlab.ui.control.Label
        q6Label                         matlab.ui.control.Label
        EEPosEditField_Y                matlab.ui.control.NumericEditField
        EEPosEditField_Z                matlab.ui.control.NumericEditField
        EndEffectorPositionEditFieldLabel  matlab.ui.control.Label
        EEPosEditField_X                matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        EEOriEditField_Y                matlab.ui.control.NumericEditField
        EEOriEditField_Z                matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        EndEffectorOrientatonEditFieldLabel  matlab.ui.control.Label
        EEOriEditField_X                matlab.ui.control.NumericEditField
        RobotReadyLampLabel             matlab.ui.control.Label
        RobotReadyLamp                  matlab.ui.control.Lamp
        QuitButton                      matlab.ui.control.Button
        TableCameraFeed                 matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        EnableCameraButton_2            matlab.ui.control.StateButton
        TextArea_5                      matlab.ui.control.TextArea
        LettersDetectedLabel_3          matlab.ui.control.Label
        CancelMovementButton_2          matlab.ui.control.Button
        EnableCameraMovementControlCheckBox_2  matlab.ui.control.CheckBox
        EnableImageProcessingCheckBox   matlab.ui.control.CheckBox
        ConveyorCameraFeedTab_2         matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        EnableCameraButton              matlab.ui.control.StateButton
        TextArea_4                      matlab.ui.control.TextArea
        LettersDetectedLabel_2          matlab.ui.control.Label
        CancelMovementButton            matlab.ui.control.Button
        EnableCameraMovementControlCheckBox  matlab.ui.control.CheckBox
        EnableImageProcessingCheckBox_2  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        ReconnectButton_Pressed = 0;
        DirectionSwitch_Changed = 0;
        PumpSwitch_Changed = 0;
        VacRunButton_Pressed = 0;
        ConRunButton_Pressed = 0;
        MovetoHomePositionButton_Pressed = 0;
        QuitButton_Pressed = 0;
        JogSpeedKnob_Changed = 0;
        XButton_Pressed = 0;
        XButton_2_Pressed = 0;
        YButton_Pressed = 0;
        YButton_3_Pressed = 0;
        ZButton_Pressed = 0;
        ZButton_2_Pressed = 0;
        Button_Pressed = 0;
        Button_4_Pressed = 0;
        MoveButton_Pressed = 0;
        ControlModeButton_Changed = 0;
    end


    methods (Access = private)

        % Button pushed function: StartUpRobotButton
        function StartUpRobotButtonPushed(app, event)
            app.TextArea.Value = 'IRB120 Starting Up...';  
            app.TextArea_2.Value = 'IRB120 Starting Up...'; 
            run('Startup.mlapp');
            app.TextArea.Value = 'Starting Up Successful';  
            app.TextArea_2.Value = 'Starting Up Successful'; 
        end

        % Button pushed function: ShutdownRobotButton
        function ShutdownRobotButtonPushed(app, event)
            app.TextArea.Value = 'IRB120 Shutting down...';  
            app.TextArea_2.Value = 'IRB120 Shutting down...'; 
            run('Shutdown.mlapp');
            app.TextArea.Value = 'Shutdown Successful';  
            app.TextArea_2.Value = 'Shutdown Successful'; 
        end

        % Value changed function: ConRunButton
        function ConRunButtonValueChanged(app, event)
            value = app.ConRunButton.Value;
            if(value==1)
                app.TextArea.Value = 'Conveyor Running...';
                app.TextArea_2.Value = 'Conveyor Running...';
            else
                app.TextArea.Value = 'Conveyor stopped';
                app.TextArea_2.Value = 'Conveyor stopped';
            end
            app.ConRunButton_Pressed = 1;
        end

        % Selection changed function: ControlModeButtonGroup
        function ControlModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlModeButtonGroup.SelectedObject;
            if(strcmp(selectedButton.Text,'Robot'))
                app.TextArea.Value = 'Robot Mode';
                app.TextArea_2.Value = 'Robot Mode';
            else
                app.TextArea.Value = 'Simulation Mode';
                app.TextArea_2.Value = 'Simulation Mode';
            end
            app.ControlModeButton_Changed = 1;
        end

        % Value changed function: MovetoHomePositionButton
        function MovetoHomePositionButtonValueChanged(app, event)
            value = app.MovetoHomePositionButton.Value;
            if(value==1)
                app.JogSpeedKnob.Value = 'Medium';
                app.BaseButton.Value = 1;
                app.q1Button.Value = 1;
            end
            app.MovetoHomePositionButton_Pressed = 1;
        end

        % Value changed function: MoveButton
        function MoveButtonValueChanged(app, event)
            value = app.MoveButton.Value;
            if(value==1)
                if(app.EndEffectorPositionButton.Value)
                     app.TextArea.Value = sprintf('Moving End Effector to [%f %f %f] relative to %s home',...
                         app.EndEffectorPositionEditField_X.Value, app.EndEffectorPositionEditField_Y.Value, ...
                         app.EndEffectorPositionEditField_Z.Value,  app.RelativeHomeDropDown.Value);
                     app.TextArea_2.Value = app.TextArea.Value;
                     
                elseif(app.JointAnglesButton.Value)
                     app.TextArea.Value = sprintf('Moving joints to [%f %f %f %f %f %f]',...
                         app.JointAnglesEditField_q1.Value, app.JointAnglesEditField_q2.Value, app.JointAnglesEditField_q3.Value,...
                            app.JointAnglesEditField_q4.Value, app.JointAnglesEditField_q5.Value, app.JointAnglesEditField_q6.Value);
                     app.TextArea_2.Value = app.TextArea.Value;
                     
                else
                    app.TextArea.Value = sprintf('Reorientating End Effector according to quaternion [%f %f %f %f]',...
                         app.ReorientEndEffectorEditField.Value, app.ReorientEndEffectorEditField_2.Value, ...
                         app.ReorientEndEffectorEditField_3.Value,  app.ReorientEndEffectorEditField_4.Value);
                    app.TextArea_2.Value = app.TextArea.Value;
                end
            else
                 app.TextArea.Value = 'Motion Paused';
                 app.TextArea_2.Value = app.TextArea.Value;
            end
            app.MoveButton_Pressed = 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EndEffectorPositionEditField_X.Value = 0;
            app.EndEffectorPositionEditField_Y.Value = 0;
            app.EndEffectorPositionEditField_Z.Value = 0;
            
            app.JointAnglesEditField_q1.Value = 0;
            app.JointAnglesEditField_q2.Value = 0;
            app.JointAnglesEditField_q3.Value = 0;
            app.JointAnglesEditField_q4.Value = 0;
            app.JointAnglesEditField_q5.Value = 0;
            app.JointAnglesEditField_q6.Value = 0;
            
            app.ReorientEndEffectorEditField.Value = 0;
            app.ReorientEndEffectorEditField_2.Value = 0;
            app.ReorientEndEffectorEditField_3.Value = 0;  
            app.ReorientEndEffectorEditField_4.Value = 0;
            
            app.EndEffectorPositionButton.Value = 1;
            app.MoveButton.Value = 0;
            
            app.RelativeHomeDropDown.Value = 'Table';
            
            app.TextArea.Value = '';  
            app.TextArea_2.Value = ''; 
        end

        % Button pushed function: ResetIOsButton
        function ResetIOsButtonPushed(app, event)
            app.DirectionSwitch.Value = 'Backward';
            app.ConRunButton.Value = 0;
            app.PumpSwitch.Value = 'Off';
            app.VacRunButton.Value = 0;
            app.TextArea.Value = '';  
            app.TextArea_2.Value = ''; 
            
            app.DirectionSwitch_Changed = 1;
            app.PumpSwitch_Changed = 1;
            app.VacRunButton_Pressed = 1;
            app.ConRunButton_Pressed = 1;
        end

        % Value changed function: VacRunButton
        function VacRunButtonValueChanged(app, event)
            value = app.VacRunButton.Value;
            if(value==1)
                app.TextArea.Value = 'Vacuum Running...';
                app.TextArea_2.Value = 'Vacuum Running...';
            else
                app.TextArea.Value = 'Vacuum stopped';
                app.TextArea_2.Value = 'Vacuum stopped';
            end
            app.VacRunButton_Pressed = 1;
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.ReconnectButton_Pressed = 1;
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            value = app.DirectionSwitch.Value;
            app.DirectionSwitch_Changed = 1;
        end

        % Value changed function: PumpSwitch
        function PumpSwitchValueChanged(app, event)
            value = app.PumpSwitch.Value;
            app.PumpSwitch_Changed = 1;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.QuitButton_Pressed = 1;
        end

        % Value changed function: JogSpeedKnob
        function JogSpeedKnobValueChanged(app, event)
            value = app.JogSpeedKnob.Value;
            app.JogSpeedKnob_Changed = 1;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.XButton_Pressed = 1;
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.XButton_2_Pressed = 1;
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            app.YButton_Pressed = 1;
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            app.YButton_3_Pressed = 1;
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.ZButton_Pressed = 1;
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            app.ZButton_2_Pressed = 1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button_Pressed = 1;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Button_4_Pressed = 1;
        end

        % Value changed function: 
        % EnableCameraMovementControlCheckBox_2
        function EnableCameraMovementControlCheckBox_2ValueChanged(app, event)
            value = app.EnableCameraMovementControlCheckBox_2.Value;
            
        end

        % Value changed function: EnableCameraButton_2
        function EnableCameraButton_2ValueChanged(app, event)
            value = app.EnableCameraButton_2.Value;
            
        end

        % Value changed function: EnableImageProcessingCheckBox
        function EnableImageProcessingCheckBoxValueChanged(app, event)
            value = app.EnableImageProcessingCheckBox.Value;
            
        end

        % Value changed function: EnableCameraButton
        function EnableCameraButtonValueChanged(app, event)
            value = app.EnableCameraButton.Value;
            
        end

        % Value changed function: EnableCameraMovementControlCheckBox
        function EnableCameraMovementControlCheckBoxValueChanged(app, event)
            value = app.EnableCameraMovementControlCheckBox.Value;
            
        end

        % Value changed function: EnableImageProcessingCheckBox_2
        function EnableImageProcessingCheckBox_2ValueChanged(app, event)
            value = app.EnableImageProcessingCheckBox_2.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IRB120ControlTab = uitab(app.TabGroup);
            app.IRB120ControlTab.Title = 'IRB120 Control';
            app.IRB120ControlTab.Units = 'pixels';
            app.JoggingPanel = uipanel(app.IRB120ControlTab);
            app.JoggingPanel.Title = 'Jogging';
            app.JogSpeedKnobLabel = uilabel(app.JoggingPanel);
            app.JogSpeedKnobLabel.Text = 'Jog Speed';
            app.JogSpeedKnob = uiknob(app.JoggingPanel, 'discrete');
            app.JogSpeedKnob.Items = {'Fine', 'Slow', 'Medium', 'Fast'};
            app.JogSpeedKnob.ValueChangedFcn = createCallbackFcn(app, @JogSpeedKnobValueChanged, true);
            app.JogSpeedKnob.Value = 'Medium';
            app.TabGroup2 = uitabgroup(app.JoggingPanel);
            app.LinearModeTab = uitab(app.TabGroup2);
            app.LinearModeTab.Title = 'Linear Mode';
            app.LinearModeTab.Units = 'pixels';
            app.JogFrameButtonGroup = uibuttongroup(app.LinearModeTab);
            app.JogFrameButtonGroup.Title = 'Jog Frame';
            app.BaseButton = uiradiobutton(app.JogFrameButtonGroup);
            app.BaseButton.Text = 'Base';
            app.BaseButton.Value = true;
            app.EndEffectorButton = uiradiobutton(app.JogFrameButtonGroup);
            app.EndEffectorButton.Text = 'End Effector';
            app.YButton = uibutton(app.LinearModeTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y-';
            app.XButton_2 = uibutton(app.LinearModeTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X-';
            app.YButton_3 = uibutton(app.LinearModeTab, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Text = 'Y+';
            app.XButton = uibutton(app.LinearModeTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X+';
            app.ZButton_2 = uibutton(app.LinearModeTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = 'Z-';
            app.ZButton = uibutton(app.LinearModeTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z+';
            app.JointModeTab = uitab(app.TabGroup2);
            app.JointModeTab.Title = 'Joint Mode';
            app.JointModeTab.Units = 'pixels';
            app.JointButtonGroup = uibuttongroup(app.JointModeTab);
            app.JointButtonGroup.Title = 'Joint';
            app.q1Button = uitogglebutton(app.JointButtonGroup);
            app.q1Button.Text = 'q1';
            app.q1Button.Value = true;
            app.q2Button = uitogglebutton(app.JointButtonGroup);
            app.q2Button.Text = 'q2';
            app.q3Button = uitogglebutton(app.JointButtonGroup);
            app.q3Button.Text = 'q3';
            app.q4Button = uitogglebutton(app.JointButtonGroup);
            app.q4Button.Text = 'q4';
            app.q5Button = uitogglebutton(app.JointButtonGroup);
            app.q5Button.Text = 'q5';
            app.q6Button = uitogglebutton(app.JointButtonGroup);
            app.q6Button.Text = 'q6';
            app.Button = uibutton(app.JointModeTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_4 = uibutton(app.JointModeTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.SetPosePanel = uipanel(app.JoggingPanel);
            app.SetPosePanel.Title = 'Set Pose';
            app.RelativeHomeDropDownLabel = uilabel(app.SetPosePanel);
            app.RelativeHomeDropDownLabel.Text = 'Relative Home';
            app.RelativeHomeDropDown = uidropdown(app.SetPosePanel);
            app.RelativeHomeDropDown.Items = {'Table', 'Conveyor'};
            app.RelativeHomeDropDown.Value = 'Table';
            app.EndEffectorPositionEditField_Y = uieditfield(app.SetPosePanel, 'numeric');
            app.EndEffectorPositionEditField_Y.Limits = [-580 580];
            app.EndEffectorPositionEditField_Y.ValueDisplayFormat = '%5.2f';
            app.EndEffectorPositionEditField_Z = uieditfield(app.SetPosePanel, 'numeric');
            app.EndEffectorPositionEditField_Z.Limits = [-50 870];
            app.EndEffectorPositionEditField_Z.ValueDisplayFormat = '%5.2f';
            app.XLabel_3 = uilabel(app.SetPosePanel);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.SetPosePanel);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.SetPosePanel);
            app.ZLabel_3.Text = 'Z';
            app.EndEffectorPositionEditField_X = uieditfield(app.SetPosePanel, 'numeric');
            app.EndEffectorPositionEditField_X.Limits = [-580 580];
            app.EndEffectorPositionEditField_X.ValueDisplayFormat = '%5.2f';
            app.JointAnglesEditField_q2 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q2.Limits = [-110 110];
            app.JointAnglesEditField_q2.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_q3 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q3.Limits = [-110 70];
            app.JointAnglesEditField_q3.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_q4 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q4.Limits = [-160 160];
            app.JointAnglesEditField_q4.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_q5 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q5.Limits = [-120 120];
            app.JointAnglesEditField_q5.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_q6 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q6.Limits = [-400 400];
            app.JointAnglesEditField_q6.ValueDisplayFormat = '%5.1f';
            app.q1Label_2 = uilabel(app.SetPosePanel);
            app.q1Label_2.Text = 'q1';
            app.q2Label_2 = uilabel(app.SetPosePanel);
            app.q2Label_2.Text = 'q2';
            app.q3Label_2 = uilabel(app.SetPosePanel);
            app.q3Label_2.Text = 'q3';
            app.q4Label_2 = uilabel(app.SetPosePanel);
            app.q4Label_2.Text = 'q4';
            app.q5Label_2 = uilabel(app.SetPosePanel);
            app.q5Label_2.Text = 'q5';
            app.q6Label_2 = uilabel(app.SetPosePanel);
            app.q6Label_2.Text = 'q6';
            app.JointAnglesEditField_q1 = uieditfield(app.SetPosePanel, 'numeric');
            app.JointAnglesEditField_q1.Limits = [-165 165];
            app.JointAnglesEditField_q1.ValueDisplayFormat = '%5.1f';
            app.ReorientEndEffectorEditField_2 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_2.ValueDisplayFormat = '%5.2f';
            app.ReorientEndEffectorEditField_3 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_3.ValueDisplayFormat = '%5.2f';
            app.ReorientEndEffectorEditField = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField.ValueDisplayFormat = '%5.2f';
            app.ReorientEndEffectorEditField_4 = uieditfield(app.SetPosePanel, 'numeric');
            app.ReorientEndEffectorEditField_4.ValueDisplayFormat = '%5.2f';
            app.QuarternionLabel = uilabel(app.SetPosePanel);
            app.QuarternionLabel.Text = 'Quarternion';
            app.MoveButton = uibutton(app.SetPosePanel, 'state');
            app.MoveButton.ValueChangedFcn = createCallbackFcn(app, @MoveButtonValueChanged, true);
            app.MoveButton.Text = 'Move';
            app.ResetButton = uibutton(app.SetPosePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.InputMethodButtonGroup = uibuttongroup(app.SetPosePanel);
            app.InputMethodButtonGroup.Title = 'Input Method';
            app.EndEffectorPositionButton = uitogglebutton(app.InputMethodButtonGroup);
            app.EndEffectorPositionButton.Text = 'End Effector Position';
            app.EndEffectorPositionButton.Value = true;
            app.JointAnglesButton = uitogglebutton(app.InputMethodButtonGroup);
            app.JointAnglesButton.Text = 'Joint Angles';
            app.ReorientEndEffectorButton = uitogglebutton(app.InputMethodButtonGroup);
            app.ReorientEndEffectorButton.Text = 'Reorient End Effector';
            app.MovetoHomePositionButton = uibutton(app.JoggingPanel, 'state');
            app.MovetoHomePositionButton.ValueChangedFcn = createCallbackFcn(app, @MovetoHomePositionButtonValueChanged, true);
            app.MovetoHomePositionButton.Text = {'Move to Home'; ' Position'};
            app.MATLABCommandsPanel = uipanel(app.IRB120ControlTab);
            app.MATLABCommandsPanel.Title = 'MATLAB Commands';
            app.TextArea = uitextarea(app.MATLABCommandsPanel);
            app.RAPIDCommandsPanel = uipanel(app.IRB120ControlTab);
            app.RAPIDCommandsPanel.Title = 'RAPID Commands';
            app.TextArea_2 = uitextarea(app.RAPIDCommandsPanel);
            app.DIOsPanel = uipanel(app.IRB120ControlTab);
            app.DIOsPanel.Title = 'DIOs';
            app.EmergencyStopLampLabel = uilabel(app.DIOsPanel);
            app.EmergencyStopLampLabel.Text = 'Emergency Stop';
            app.EmergencyStopLamp = uilamp(app.DIOsPanel);
            app.LightCurtainLampLabel = uilabel(app.DIOsPanel);
            app.LightCurtainLampLabel.Text = 'Light Curtain';
            app.LightCurtainLamp = uilamp(app.DIOsPanel);
            app.MotorsONLampLabel = uilabel(app.DIOsPanel);
            app.MotorsONLampLabel.Text = 'Motors ON';
            app.MotorsONLamp = uilamp(app.DIOsPanel);
            app.MotorsEnableLampLabel = uilabel(app.DIOsPanel);
            app.MotorsEnableLampLabel.Text = 'Motors Enable';
            app.MotorsEnableLamp = uilamp(app.DIOsPanel);
            app.ExecutionErrorLampLabel = uilabel(app.DIOsPanel);
            app.ExecutionErrorLampLabel.Text = 'Execution Error';
            app.ExecutionErrorLamp = uilamp(app.DIOsPanel);
            app.ResetIOsButton = uibutton(app.DIOsPanel, 'push');
            app.ResetIOsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetIOsButtonPushed, true);
            app.ResetIOsButton.Text = 'Reset IOs';
            app.ConveyorLabel = uilabel(app.DIOsPanel);
            app.ConveyorLabel.Text = 'Conveyor';
            app.VacuumLabel = uilabel(app.DIOsPanel);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConveyorStatusLampLabel = uilabel(app.DIOsPanel);
            app.ConveyorStatusLampLabel.Text = 'Conveyor Status';
            app.ConveyorStatusLamp = uilamp(app.DIOsPanel);
            app.ConRunButton = uibutton(app.DIOsPanel, 'state');
            app.ConRunButton.ValueChangedFcn = createCallbackFcn(app, @ConRunButtonValueChanged, true);
            app.ConRunButton.Text = 'ConRun';
            app.DirectionSwitchLabel = uilabel(app.DIOsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.DirectionSwitch.Items = {'Backward', 'Forward'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'Backward';
            app.PumpSwitchLabel = uilabel(app.DIOsPanel);
            app.PumpSwitchLabel.Text = 'Pump';
            app.PumpSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.PumpSwitch.ValueChangedFcn = createCallbackFcn(app, @PumpSwitchValueChanged, true);
            app.VacRunButton = uibutton(app.DIOsPanel, 'state');
            app.VacRunButton.ValueChangedFcn = createCallbackFcn(app, @VacRunButtonValueChanged, true);
            app.VacRunButton.Text = 'VacRun';
            app.DirectionLampLabel = uilabel(app.DIOsPanel);
            app.DirectionLampLabel.Text = 'Direction';
            app.DirectionLamp = uilamp(app.DIOsPanel);
            app.ConRunLampLabel = uilabel(app.DIOsPanel);
            app.ConRunLampLabel.Text = 'ConRun';
            app.ConRunLamp = uilamp(app.DIOsPanel);
            app.PumpLampLabel = uilabel(app.DIOsPanel);
            app.PumpLampLabel.Text = 'Pump';
            app.PumpLamp = uilamp(app.DIOsPanel);
            app.VacSolLampLabel = uilabel(app.DIOsPanel);
            app.VacSolLampLabel.Text = 'VacSol';
            app.VacSolLamp = uilamp(app.DIOsPanel);
            app.MotionSupervisionLampLabel = uilabel(app.DIOsPanel);
            app.MotionSupervisionLampLabel.Text = 'Motion Supervision';
            app.MotionSupervisionLamp = uilamp(app.DIOsPanel);
            app.RobotManagementPanel = uipanel(app.IRB120ControlTab);
            app.RobotManagementPanel.Title = 'Robot Management';
            app.StartUpRobotButton = uibutton(app.RobotManagementPanel, 'push');
            app.StartUpRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartUpRobotButtonPushed, true);
            app.StartUpRobotButton.Text = 'Start Up Robot';
            app.ShutdownRobotButton = uibutton(app.RobotManagementPanel, 'push');
            app.ShutdownRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownRobotButtonPushed, true);
            app.ShutdownRobotButton.Text = 'Shutdown Robot';
            app.ConnectionStatusLampLabel = uilabel(app.RobotManagementPanel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RobotManagementPanel);
            app.ReconnectButton = uibutton(app.RobotManagementPanel, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.ControlModeButtonGroup = uibuttongroup(app.RobotManagementPanel);
            app.ControlModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControlModeButtonGroupSelectionChanged, true);
            app.ControlModeButtonGroup.Title = 'Control Mode';
            app.SimulationButton = uiradiobutton(app.ControlModeButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.RobotButton = uiradiobutton(app.ControlModeButtonGroup);
            app.RobotButton.Text = 'Robot';
            app.CurrentRobotStatusPanel = uipanel(app.IRB120ControlTab);
            app.CurrentRobotStatusPanel.Title = 'Current Robot Status';
            app.JointAnglesEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.JointAnglesEditFieldLabel.Text = 'Joint Angles';
            app.JointAnglesEditField = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_2 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_2.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_3 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_3.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_4 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_4.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_5 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_5.ValueDisplayFormat = '%5.1f';
            app.JointAnglesEditField_6 = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.JointAnglesEditField_6.ValueDisplayFormat = '%5.1f';
            app.q1Label = uilabel(app.CurrentRobotStatusPanel);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.CurrentRobotStatusPanel);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.CurrentRobotStatusPanel);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.CurrentRobotStatusPanel);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.CurrentRobotStatusPanel);
            app.q5Label.Text = 'q5';
            app.q6Label = uilabel(app.CurrentRobotStatusPanel);
            app.q6Label.Text = 'q6';
            app.EEPosEditField_Y = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEPosEditField_Y.ValueDisplayFormat = '%5.2f';
            app.EEPosEditField_Z = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEPosEditField_Z.ValueDisplayFormat = '%5.2f';
            app.EndEffectorPositionEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.EndEffectorPositionEditFieldLabel.Text = 'End Effector Position ';
            app.EEPosEditField_X = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEPosEditField_X.ValueDisplayFormat = '%5.2f';
            app.XLabel = uilabel(app.CurrentRobotStatusPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.CurrentRobotStatusPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.CurrentRobotStatusPanel);
            app.ZLabel.Text = 'Z';
            app.EEOriEditField_Y = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEOriEditField_Y.ValueDisplayFormat = '%5.2f';
            app.EEOriEditField_Z = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEOriEditField_Z.ValueDisplayFormat = '%5.2f';
            app.XLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.XLabel_2.Text = 'X°';
            app.YLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.YLabel_2.Text = 'Y°';
            app.ZLabel_2 = uilabel(app.CurrentRobotStatusPanel);
            app.ZLabel_2.Text = 'Z°';
            app.EndEffectorOrientatonEditFieldLabel = uilabel(app.CurrentRobotStatusPanel);
            app.EndEffectorOrientatonEditFieldLabel.Text = 'End Effector Orientaton ';
            app.EEOriEditField_X = uieditfield(app.CurrentRobotStatusPanel, 'numeric');
            app.EEOriEditField_X.ValueDisplayFormat = '%5.2f';
            app.RobotReadyLampLabel = uilabel(app.CurrentRobotStatusPanel);
            app.RobotReadyLampLabel.Text = 'Robot Ready';
            app.RobotReadyLamp = uilamp(app.CurrentRobotStatusPanel);
            app.QuitButton = uibutton(app.CurrentRobotStatusPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.TableCameraFeed = uitab(app.TabGroup);
            app.TableCameraFeed.Title = 'Table Camera Feed';
            app.TableCameraFeed.Units = 'pixels';
            app.UIAxes = uiaxes(app.TableCameraFeed);
            title(app.UIAxes, 'Table Camera Feed');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.EnableCameraButton_2 = uibutton(app.TableCameraFeed, 'state');
            app.EnableCameraButton_2.ValueChangedFcn = createCallbackFcn(app, @EnableCameraButton_2ValueChanged, true);
            app.EnableCameraButton_2.Text = 'Enable Camera';
            app.TextArea_5 = uitextarea(app.TableCameraFeed);
            app.LettersDetectedLabel_3 = uilabel(app.TableCameraFeed);
            app.LettersDetectedLabel_3.Text = 'Letters Detected';
            app.CancelMovementButton_2 = uibutton(app.TableCameraFeed, 'push');
            app.CancelMovementButton_2.Text = 'Cancel Movement';
            app.EnableCameraMovementControlCheckBox_2 = uicheckbox(app.TableCameraFeed);
            app.EnableCameraMovementControlCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnableCameraMovementControlCheckBox_2ValueChanged, true);
            app.EnableCameraMovementControlCheckBox_2.Text = 'Enable Camera Movement Control';
            app.EnableImageProcessingCheckBox = uicheckbox(app.TableCameraFeed);
            app.EnableImageProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableImageProcessingCheckBoxValueChanged, true);
            app.EnableImageProcessingCheckBox.Text = ' Enable Image Processing';
            app.ConveyorCameraFeedTab_2 = uitab(app.TabGroup);
            app.ConveyorCameraFeedTab_2.Title = 'Conveyor Camera Feed';
            app.ConveyorCameraFeedTab_2.Units = 'pixels';
            app.UIAxes_2 = uiaxes(app.ConveyorCameraFeedTab_2);
            title(app.UIAxes_2, 'Conveyor Camera Feed');
            xlabel(app.UIAxes_2, 'X');
            ylabel(app.UIAxes_2, 'Y');
            app.EnableCameraButton = uibutton(app.ConveyorCameraFeedTab_2, 'state');
            app.EnableCameraButton.ValueChangedFcn = createCallbackFcn(app, @EnableCameraButtonValueChanged, true);
            app.EnableCameraButton.Text = 'Enable Camera';
            app.TextArea_4 = uitextarea(app.ConveyorCameraFeedTab_2);
            app.LettersDetectedLabel_2 = uilabel(app.ConveyorCameraFeedTab_2);
            app.LettersDetectedLabel_2.Text = 'Letters Detected';
            app.CancelMovementButton = uibutton(app.ConveyorCameraFeedTab_2, 'push');
            app.CancelMovementButton.Text = 'Cancel Movement';
            app.EnableCameraMovementControlCheckBox = uicheckbox(app.ConveyorCameraFeedTab_2);
            app.EnableCameraMovementControlCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCameraMovementControlCheckBoxValueChanged, true);
            app.EnableCameraMovementControlCheckBox.Text = 'Enable Camera Movement Control';
            app.EnableImageProcessingCheckBox_2 = uicheckbox(app.ConveyorCameraFeedTab_2);
            app.EnableImageProcessingCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnableImageProcessingCheckBox_2ValueChanged, true);
            app.EnableImageProcessingCheckBox_2.Text = ' Enable Image Processing';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IRB120GUI_temp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end